classdef BearingCapacityPredictingANN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ADDITIONALINPUTSPanel           matlab.ui.container.Panel
        GroundwatertableleveldepthmEditField  matlab.ui.control.NumericEditField
        GroundwatertableleveldepthmEditFieldLabel  matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        Saturatedunitweight_satkNm3EditField  matlab.ui.control.NumericEditField
        SaturatedunitweightkNm3Label    matlab.ui.control.Label
        UnitweightkNm3EditField         matlab.ui.control.NumericEditField
        UnitweightkNm3EditFieldLabel    matlab.ui.control.Label
        bearingcapacityqukNm2EditField  matlab.ui.control.NumericEditField
        bearingcapacityqukNm2EditFieldLabel  matlab.ui.control.Label
        ANNINPUTSPanel                  matlab.ui.container.Panel
        SoileffectivestresskNm2EditField  matlab.ui.control.NumericEditField
        SoileffectivestresskNm2EditFieldLabel  matlab.ui.control.Label
        SoileffectivecohesionckNm2EditField  matlab.ui.control.NumericEditField
        SoileffectivecohesionckNm2EditFieldLabel  matlab.ui.control.Label
        AverageeffectiveunitweightkNm3EditField  matlab.ui.control.NumericEditField
        AverageeffectiveunitweightkNm3EditFieldLabel  matlab.ui.control.Label
        SoileffectivefrictionangleEditField  matlab.ui.control.NumericEditField
        SoileffectivefrictionangleLabel  matlab.ui.control.Label
        FoundationembedmentdepthDfmEditField  matlab.ui.control.NumericEditField
        FoundationembedmentdepthDfmEditFieldLabel  matlab.ui.control.Label
        FoundationlengthLmEditField     matlab.ui.control.NumericEditField
        FoundationlengthLLabel          matlab.ui.control.Label
        FoundationwidthBmEditField      matlab.ui.control.NumericEditField
        FoundationwidthBmEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            gwl = app.GroundwatertableleveldepthmEditField.Value;
            Df = app.FoundationembedmentdepthDfmEditField.Value;
            b = app.FoundationwidthBmEditField.Value;
            eff = app.AverageeffectiveunitweightkNm3EditField.Value;
            undr = app.UnitweightkNm3EditField.Value;
            sat = app.Saturatedunitweight_satkNm3EditField.Value;
            str = app.SoileffectivestresskNm2EditField.Value;
            d = gwl - Df;

            if gwl <= Df
               eff = sat - 9.81;
               str = (gwl*undr) + ((Df - gwl)*eff);
            else
                if d <= b
                    eff = (sat - 9.81) + ((d/b)*(undr-(sat - 9.81)));
                    str = undr*Df;
                else
                    eff = undr;
                    str = undr*Df;
                end
            end

            app.AverageeffectiveunitweightkNm3EditField.Value = eff;
            app.SoileffectivestresskNm2EditField.Value = str;
            
            app.bearingcapacityqukNm2EditField.Value = BearingCapacityNNetFxn([app.FoundationwidthBmEditField.Value, app.FoundationlengthLmEditField.Value, app.FoundationembedmentdepthDfmEditField.Value, app.SoileffectivefrictionangleEditField.Value, app.AverageeffectiveunitweightkNm3EditField.Value, app.SoileffectivecohesionckNm2EditField.Value, app.SoileffectivestresskNm2EditField.Value]');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'NeuralNetworkArchitecture1.png');
            app.ANNINPUTSPanel = uipanel(app.UIFigure);
            app.ANNINPUTSPanel.Title = 'ANN INPUTS';
            app.FoundationwidthBmEditFieldLabel = uilabel(app.ANNINPUTSPanel);
            app.FoundationwidthBmEditFieldLabel.Text = 'Foundation width, B (m)';
            app.FoundationwidthBmEditField = uieditfield(app.ANNINPUTSPanel, 'numeric');
            app.FoundationlengthLLabel = uilabel(app.ANNINPUTSPanel);
            app.FoundationlengthLLabel.Text = 'Foundation length, L (m)';
            app.FoundationlengthLmEditField = uieditfield(app.ANNINPUTSPanel, 'numeric');
            app.FoundationembedmentdepthDfmEditFieldLabel = uilabel(app.ANNINPUTSPanel);
            app.FoundationembedmentdepthDfmEditFieldLabel.Text = 'Foundation embedment depth, Df (m)';
            app.FoundationembedmentdepthDfmEditField = uieditfield(app.ANNINPUTSPanel, 'numeric');
            app.SoileffectivefrictionangleLabel = uilabel(app.ANNINPUTSPanel);
            app.SoileffectivefrictionangleLabel.Text = 'Soil effective friction angle, ϕ'' (°)';
            app.SoileffectivefrictionangleEditField = uieditfield(app.ANNINPUTSPanel, 'numeric');
            app.AverageeffectiveunitweightkNm3EditFieldLabel = uilabel(app.ANNINPUTSPanel);
            app.AverageeffectiveunitweightkNm3EditFieldLabel.Text = 'Average effective unit weight, γ ̅ (kN/m^3)';
            app.AverageeffectiveunitweightkNm3EditField = uieditfield(app.ANNINPUTSPanel, 'numeric');
            app.AverageeffectiveunitweightkNm3EditField.Editable = 'off';
            app.AverageeffectiveunitweightkNm3EditField.Tooltip = {'This will be automatically calculated when you enter the additional inputs'' values'};
            app.SoileffectivecohesionckNm2EditFieldLabel = uilabel(app.ANNINPUTSPanel);
            app.SoileffectivecohesionckNm2EditFieldLabel.Text = 'Soil effective cohesion, c'' (kN/m^2)';
            app.SoileffectivecohesionckNm2EditField = uieditfield(app.ANNINPUTSPanel, 'numeric');
            app.SoileffectivestresskNm2EditFieldLabel = uilabel(app.ANNINPUTSPanel);
            app.SoileffectivestresskNm2EditFieldLabel.Text = 'Soil effective stress σ'' (kN/m^2)';
            app.SoileffectivestresskNm2EditField = uieditfield(app.ANNINPUTSPanel, 'numeric');
            app.SoileffectivestresskNm2EditField.Editable = 'off';
            app.SoileffectivestresskNm2EditField.Tooltip = {'This will be automatically calculated when you enter the additional inputs'' values'};
            app.bearingcapacityqukNm2EditFieldLabel = uilabel(app.UIFigure);
            app.bearingcapacityqukNm2EditFieldLabel.Text = 'bearing capacity, qu  (kN/m^2)';
            app.bearingcapacityqukNm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ADDITIONALINPUTSPanel = uipanel(app.UIFigure);
            app.ADDITIONALINPUTSPanel.Title = 'ADDITIONAL INPUTS';
            app.UnitweightkNm3EditFieldLabel = uilabel(app.ADDITIONALINPUTSPanel);
            app.UnitweightkNm3EditFieldLabel.Text = 'Unit weight, γ (kN/m^3)';
            app.UnitweightkNm3EditField = uieditfield(app.ADDITIONALINPUTSPanel, 'numeric');
            app.SaturatedunitweightkNm3Label = uilabel(app.ADDITIONALINPUTSPanel);
            app.SaturatedunitweightkNm3Label.Text = 'Saturated unit weight, γ_sat (kN/m^3)';
            app.Saturatedunitweight_satkNm3EditField = uieditfield(app.ADDITIONALINPUTSPanel, 'numeric');
            app.ExecuteButton = uibutton(app.ADDITIONALINPUTSPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.GroundwatertableleveldepthmEditFieldLabel = uilabel(app.ADDITIONALINPUTSPanel);
            app.GroundwatertableleveldepthmEditFieldLabel.Text = 'Groundwater table level/depth (m)';
            app.GroundwatertableleveldepthmEditField = uieditfield(app.ADDITIONALINPUTSPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BearingCapacityPredictingANN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end